classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
    end

    
    methods (Access = private)
        
        function initiateImageAxesComponent(~, component)
            component.Visible = 'off';
            component.Colormap = gray(256);
            axis(component, 'image');
        end
        
        function updateImage(app, fname, tab_num)
            % techniqueDropDownArr = [app.GradientTechniqueDropDown, app.LaplacianTechniqueDropDown];
            
            % Read the image
            try
                im = imread(fname);
            catch ME
                % if problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end

            % assign current tab input
            currInputImageAxes = app.inputImageAxesArr(tab_num);

            % assign current tab edge
            currEdgeImageAxes = app.edgeImageAxesArr(tab_num);
            
            % assign current tab segmented object
            currSegmentImageAxes = app.segmentImageAxesArr(tab_num);    

            % display the image
            imagesc(currInputImageAxes, im);

            % create histograms based on number of color channel
            switch size(im,3)
                case 1
                    % Display the grayscale image
                    edge_only = getOutput(app, im, tab_num);
                    segmented = segment(edge_only, im) %, lower(techniqueDropDownArr(tab_num).Value));
                    imagesc(currEdgeImageAxes, edge_only);
                    imagesc(currSegmentImageAxes, segmented);

                case 3
                    edge_1 = getOutput(app, im(:,:,1), tab_num);
                    edge_2 = getOutput(app, im(:,:,2), tab_num);
                    edge_3 = getOutput(app, im(:,:,3), tab_num);
                    edge_only = edge_1 | edge_2 | edge_3;
                    segmented = segment(edge_only, im) %lower(techniqueDropDownArr(tab_num).Value));
                    % Do bitwise AND to merge all channels
                    imagesc(currEdgeImageAxes, edge_only);
                    imagesc(currSegmentImageAxes, segmented);

                    % Alternatively. convert to grayscale first
%                     imgOut = getOutput(app, rgb2gray(im), tab_num);
%                     imagesc(currEdgeImageAxes, imgOut);

                otherwise
                    % Error when image is not grayscale or truecolor
                    uialert(app.UIFigure, 'Image must be grayscale or truecolor.', 'Image Error');
                    return;
            end 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initiate the image axes
            app.inputImageAxesArr = [app.GradientOriAxes, app.LaplacianOriAxes];
            app.edgeImageAxesArr = [app.GradientEdgeAxes, app.LaplacianEdgeAxes];
            app.segmentImageAxesArr = [app.GradientSegmentAxes, app.LaplacianSegmentAxes];

            % image path
            path = [pwd filesep '..' filesep 'images' filesep];

            % Show loading dialog
            d = uiprogressdlg(app.UIFigure, 'Title', 'Segmenting image...', 'Indeterminate', 'on');
            drawnow

            %% Gradient
            initiateImageAxesComponent(app, app.GradientOriAxes);
            initiateImageAxesComponent(app, app.GradientEdgeAxes);
            initiateImageAxesComponent(app, app.GradientSegmentAxes);
            app.gradientFname = [path 'buah.jpg'];
            if strcmp(app.GradientTechniqueDropDown.Value, 'Canny')
                % Hide Threshold
                app.GradientThresholdEditField.Visible = "off";
                app.ThresholdEditFieldLabel_2.Visible = "off";
            else
                % Hide T1, T2, sigma
                app.GradientT1EditField.Visible = "off";
                app.T1Label.Visible = "off";
                app.GradientT2EditField.Visible = "off";
                app.T1Label_2.Visible = "off";
                app.GradientSigmaEditField.Visible = "off";
                app.SigmaEditFieldLabel_2.Visible = "off";

            end

            updateImage(app, app.gradientFname, 1);

            %% Laplacian
            initiateImageAxesComponent(app, app.LaplacianOriAxes);
            initiateImageAxesComponent(app, app.LaplacianEdgeAxes);
            initiateImageAxesComponent(app, app.LaplacianSegmentAxes);
            app.laplacianFname = [path 'buah.jpg'];
            if strcmp(app.LaplacianTechniqueDropDown.Value, 'Laplacian')
                % Hide sigma
                app.LaplacianSigmaEditField.Visible = "off";
                app.SigmaEditFieldLabel.Visible = "off";

                 % Hide mask dimension
                app.LaplacianMaskDimensionEditField.Visible = "off";
                app.MaskDimensionLabel.Visible = "off";
            elseif strcmp(app.LaplacianTechniqueDropDown.Value, 'LoG')
                app.LaplacianVersionDropDown.Visible = "off"; % Hide Laplacian version dropdown
            end
            
            updateImage(app, app.laplacianFname, 2);

            % Stop loading dialog
            close(d);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end